classdef patient < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        Lamp      matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        t
        checkFun
        name
        used
        datalist
        physician
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            
            left = app.datalist(5);
            bolus = app.datalist(2);
            [usedhr,usedday,app.used,YorN] = app.checkFun.checkbolus(app.t,left,bolus,app.used);
            app.datalist(3) = usedday;
            app.datalist(4) = usedhr;
            if YorN == 1
                app.Lamp.Color = [0,1,0];
                app.datalist(4) = left-app.datalist(1);
            else
                app.Lamp.Color = [1,0,0];
            end
                
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = patient

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end